classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SealLabel           matlab.ui.control.Label
        Funciones           matlab.ui.control.DropDown
        ColorDropDownLabel  matlab.ui.control.Label
        ColorDropDown       matlab.ui.control.DropDown
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
      funcTitles = ["Ruido", "Luz", "Velocidad de un vehiculo", "Presion", "Humedad", "Oscilacion termica diurna", "Gas", "Intensidad de Luz", "Temperatura corporal", "Cardiaca"];
    end
    
    properties (Access = public)
        ChangeColorButton matlab.ui.control.Button % Agrega un botón para cambiar el color
        Color = 'r';
    end
    methods (Access = private)
    function addLibToPath(app)
        addpath(fullfile(app.PathToApp, 'lib'));
    end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Funciones
        function FuncionesValueChanged(app, event)
            selectedFunction = app.Funciones.Value;
            indexfunc= strcmp(selectedFunction,app.funcTitles);
            f=join(string(num2str(indexfunc)));
            display(f)
            title(app.UIAxes,"Señal"+" "+"de"+" "+selectedFunction);

            if     f=="1  0  0  0  0  0  0  0  0  0"
                   % Define los parámetros de la señal de ruido
                    mu = 0;     % Valor medio
                    sigma = 1;  % Desviación estándar
                    
                    % Define el tiempo de simulación (10 minutos)
                    t_sim = 0:1:600;
                    
                    % Genera el vector de valores de ruido a lo largo del tiempo
                    noise = mu + sigma * randn(size(t_sim));
                    
                    % Grafica la señal de ruido en el objeto Axes
                    plot(app.UIAxes, t_sim, noise,app.Color);
                    
                    % Agrega ejes x y y
                    xlabel(app.UIAxes, 'Tiempo [s]');
                    ylabel(app.UIAxes, 'Amplitud');

                   
            elseif f=="0  1  0  0  0  0  0  0  0  0"
                % Define los parámetros de la señal de luz solar
                    I0 = 1000;   % Intensidad de la luz solar media
                    A = 100;     % Amplitud de la variación de la intensidad de la luz solar
                    f = 1/60;  % Frecuencia (cada 60 segundos)
                    phi = 0;   % Fase
                    
                    % Define el tiempo de simulación (10 minutos)
                    t_sim = 0:1:600;
                    
                    % Genera el vector de valores de luz solar a lo largo del tiempo
                    I = I0 + A * square(2*pi*f*t_sim + phi, 50);
                    
                    % Grafica la señal de luz solar en el objeto Axes
                    plot(app.UIAxes, t_sim, I,app.Color);


            elseif f=="0  0  1  0  0  0  0  0  0  0"
                    % Parámetros de entrada
                    d = 100; % Distancia recorrida en metros
                    t = 0:0.1:10; % Vector de tiempo
                    
                    % Cálculo de la velocidad
                    v = d./t;
                    
                    % Gráfica
                    bar(app.UIAxes, t,v,app.Color)
                    title(app.UIAxes, 'Velocidad vs. tiempo');
                    xlabel(app.UIAxes, 'Tiempo (s)');
                    ylabel(app.UIAxes, 'Velocidad (m/s)');

                   
            elseif f=="0  0  0  1  0  0  0  0  0  0"
           
                    % Define los parámetros de la señal de presión
                    P0 = 1013.25;   % Presión media
                    DeltaP = 100;   % Amplitud de la variación de la presión
                    f = 1/60;       % Frecuencia (cada 60 segundos)
                    phi = 0;        % Fase
                    
                    % Define el tiempo de simulación (10 minutos)
                    t_sim = 0:1:600;
                    
                    % Genera el vector de valores de presión a lo largo del tiempo
                    P = P0 + DeltaP * sin(2*pi*f*t_sim + phi) + 10*randn(size(t_sim));
                    
                    % Grafica la señal de presión como una gráfica de dispersión en el objeto Axes
                    scatter(app.UIAxes, t_sim, P,app.Color);
                    
                    % Agrega ejes x y y
                    xlabel(app.UIAxes, 'Tiempo [s]');
                    ylabel(app.UIAxes, 'Presión [hPa]');


            elseif f=="0  0  0  0  1  0  0  0  0  0"
                   % Define los parámetros de la señal de humedad
                    H0 = 50;        % Humedad media
                    DeltaH = 10;    % Amplitud de la variación de la humedad
                    f = 1/60;       % Frecuencia (cada 60 segundos)
                    phi = 0;        % Fase
                    
                    % Define el tiempo de simulación (10 minutos)
                    t_sim = 0:1:600;
                    
                    % Genera el vector de valores de humedad a lo largo del tiempo
                    H = H0 + DeltaH * sin(2*pi*f*t_sim + phi);
                    
                    % Grafica la señal de humedad como una gráfica de barras en el objeto Axes
                    bar(app.UIAxes, H,app.Color);


            elseif f=="0  0  0  0  0  1  0  0  0  0"
                    % Define los parámetros de la oscilación térmica
                    A = 10;    % Amplitud de la oscilación
                    w = 2*pi;  % Frecuencia angular
                    phi = 0;   % Fase de la oscilación
                    C = 25;    % Temperatura media
                    
                    % Genera los valores de la temperatura
                    t = linspace(0, 24, 100);  % Genera 100 puntos entre 0 y 24 horas
                    T = A*sin(w*t + phi) + C; % Calcula la temperatura en cada punto
                    
                    % Grafica la curva de la temperatura
                    plot(app.UIAxes, t, T,app.Color);
                    xlabel(app.UIAxes, 'Tiempo (horas)');
                    ylabel(app.UIAxes, 'Temperatura (°C)');
                
            elseif f=="0  0  0  0  0  0  1  0  0  0"
                   % Parámetros de entrada
                    t = 0:0.1:10; % Vector de tiempo
                    C = 50 + 10*cos(2*pi*t/10); % Concentración del gas
                    
                    % Gráfica
                    plot(app.UIAxes,t,C,app.Color);
                    title(app.UIAxes, 'Concentración de un gas en una zona determinada');
                    xlabel(app.UIAxes,'Tiempo (s)');
                    ylabel(app.UIAxes,'Concentración del gas (ppm)');

            elseif f=="0  0  0  0  0  0  0  1  0  0"
                %Parametros de entrada
                P = 100; % Potencia luminosa en lm
                A = 10; % Superficie iluminada en m²
                t = 0:0.1:10; % Vector de tiempo
                
                % Cálculo de la intensidad de luz
                I = P./A;
                
                % Gráfica
                bar(app.UIAxes ,t,I,app.Color);
                title(app.UIAxes, 'Intensidad de luz vs. tiempo');
                xlabel(app.UIAxes, 'Tiempo (s)');
                ylabel(app.UIAxes, 'Intensidad de luz (lx)');

            elseif f=="0  0  0  0  0  0  0  0  1  0"
                % Parámetros de entrada
                t = 1:24; % Horas del día
                T = 36 + 2*cos(2*pi*t/24); % Temperatura corporal
                
                % Gráfica
                bar(app.UIAxes,t,T,app.Color);
                title(app.UIAxes, 'Variación de la temperatura corporal a lo largo del día');
                xlabel(app.UIAxes,'Hora del día');
                ylabel(app.UIAxes,'Temperatura corporal (°C)');
            
            elseif f=="0  0  0  0  0  0  0  0  0  1"
                % Parámetros de entrada
                t = 0:0.1:10; % Vector de tiempo
                fc = 60; % Frecuencia cardíaca en reposo
                amp = 40; % Amplitud de la variación
                
                % Cálculo de la frecuencia cardíaca durante el ejercicio
                y = fc + amp*sin(2*pi*t/60);
                
                % Gráfica
                plot(app.UIAxes, t,y,app.Color);
                title(app.UIAxes, 'Frecuencia cardíaca durante el ejercicio');
                xlabel(app.UIAxes,'Tiempo (min)');
                ylabel(app.UIAxes,'Frecuencia cardíaca (latidos por minuto)');

            end
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            if(value=="Rojo")
                app.Color='r';
            elseif value=="Azul"
                app.Color='b';
            elseif value=="Magenta"
                app.Color='m';
            elseif value=="Cian"
                app.Color='c';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SealLabel = uilabel(app.UIFigure);
            app.SealLabel.Text = 'Señal';
            app.Funciones = uidropdown(app.UIFigure);
            app.Funciones.Items = {'Ruido', 'Luz', 'Velocidad de un vehiculo', 'Presion', 'Humedad', 'Oscilacion termica diurna', 'Gas', 'Intensidad de Luz', 'Temperatura corporal', 'Cardiaca'};
            app.Funciones.ValueChangedFcn = createCallbackFcn(app, @FuncionesValueChanged, true);
            app.Funciones.Value = 'Ruido';
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Azul', 'Rojo', 'Magenta', 'Cian'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'Azul';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end