classdef signal_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SealDropDownLabel            matlab.ui.control.Label
        Sign                         matlab.ui.control.DropDown
        AmplitudKnob_2Label          matlab.ui.control.Label
        Amplitud                     matlab.ui.control.Knob
        BaseLabel                    matlab.ui.control.Label
        Base                         matlab.ui.control.Knob
        CicloKnob_2Label             matlab.ui.control.Label
        Ciclo                        matlab.ui.control.Knob
        FrecuenciaKnob_2Label        matlab.ui.control.Label
        Frecuencia                   matlab.ui.control.Knob
        AnchoKnob_2Label             matlab.ui.control.Label
        Ancho                        matlab.ui.control.Knob
        RepresentacinLabel           matlab.ui.control.Label
        RepresentacionDrop           matlab.ui.control.DropDown
        FrecuenciaKnob_2Label_2      matlab.ui.control.Label
        FrecuenciaD                  matlab.ui.control.Knob
        EscaladetiempoLabel          matlab.ui.control.Label
        Representacion               matlab.ui.control.Slider
        ComponenteLabel              matlab.ui.control.Label
        Componente                   matlab.ui.control.DropDown
        OperacionessobrelavariableindependienteLabel  matlab.ui.control.Label
        ReflexionSwitchLabel         matlab.ui.control.Label
        ReflexionSwitch              matlab.ui.control.Switch
        FactordeEscalamientoSpinnerLabel  matlab.ui.control.Label
        FactordeEscalamientoSpinner  matlab.ui.control.Spinner
        FactordeCorrimientoSpinnerLabel  matlab.ui.control.Label
        FactordeCorrimientoSpinner   matlab.ui.control.Spinner
        UIAxes                       matlab.ui.control.UIAxes
    end

    methods (Access = private)
    function addLibToPath(app)
        addpath(fullfile(app.PathToApp, 'lib'));
    end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, as)
            
        end

        % Callback function
        function AmplitudSliderValueChanged(app, event)
            
        end

        % Value changed function: Amplitud, Ancho, Base, Ciclo, 
        % Componente, FactordeCorrimientoSpinner, 
        % FactordeEscalamientoSpinner, Frecuencia, FrecuenciaD, 
        % ReflexionSwitch, Representacion, Sign
        function SignValueChanged(app, event)
            title(app.UIAxes,app.Sign.Value);
            
            % Operaciones sobre variable independiente
            factorEscalamientoTiempo = app.FactordeEscalamientoSpinner.Value;
            
            if app.ReflexionSwitch.Value == "On"
                factorEscalamientoTiempo = -factorEscalamientoTiempo;
            end
                
            corrimiento = app.FactordeCorrimientoSpinner.Value;
             
            switch app.RepresentacionDrop.Value
              case "Continua"
                    set(app.FrecuenciaKnob_2Label_2, 'Visible', 'off');
                    set(app.FrecuenciaD, 'Visible', 'off');
                    set(app.Base, 'Visible', 'off');
                    set(app.BaseLabel, 'Visible', 'off');
                switch app.Sign.Value
                    case "Cuadrada"
                        %%  Onda Cuadrada en tiempo continuo
                            A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            f = app.Frecuencia.Value; %Frecuencia Fundamental
                            T = 3*(1/f); %Número de Periodos a Graficar
                            duty = app.Ciclo.Value; %Ciclo útil
                            t = app.Representacion.Value/100:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x = A*square(2*pi*f*t,duty); %Generación de Señal
                            plot(t,x),grid on %Grafica

                                    xe= 1/2 .* (x+fliplr(x));
                                    xo= 1/2 .* (x-fliplr(x));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end

                           %Cálculo de Potencia y Energía
                           P=(1/(t(end)-t(1))) * trapz(t,abs(x).^2);
                           E = trapz(t, abs(x).^2);
                           % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end


                    case "Diente de sierra" 
                         %% Onda Diente de Sierra Tiempo Continuo
                            A = app.Amplitud.Value; %Amplitud
                            f = app.Frecuencia.Value; %Frecuencia Fundamental
                            Fs = 10000; %Frecuencia de Muestreo
                            %T = 3*(1/f); %Número de Periodos a Graficar
                            width = app.Ancho.Value; %Ancho
                            t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento; %operaciones en variable independiente
                            x = A*sawtooth(2*pi*f*t,width); %Generación de Señal
                            plot(t,x),grid on %Grafica
                                    xe= 1/2 .* (x+fliplr(x));
                                    xo= 1/2 .* (x-fliplr(x));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                            %Cálculo de Potencia y Energía
                           P=(1/(t(end)-t(1))) * trapz(t,abs(x).^2);
                           E = trapz(t, abs(x).^2);
                           % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end

                    case "Senoidal"
                        %% Onda Senoidal Tiempo Continuo
                            A = app.Amplitud.Value; %Amplitud
                            f = app.Frecuencia.Value; %Frecuencia Fundamental
                            phi = pi/2; %Desfase en radianes
                            Fs = 10000; %Frecuencia de Muestreo
                            %T = 3*(1/f); %Número de Periodos a Graficar
                            t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x = A*sin(2*pi*f*t+phi); %Generación de Señal
                            plot(t,x),grid on %Grafica
                                    xe= 1/2 .* (x+fliplr(x));
                                    xo= 1/2 .* (x-fliplr(x));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                           % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x).^2);
                            E = trapz(t, abs(x).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end

                    case "Exponencial creciente y decreciente"
                        set(app.Base, 'Visible', 'on');
                        set(app.BaseLabel, 'Visible', 'on');
                        %% Onda Exponencial en Tiempo Continuo
                            Fs = 10000; %Frecuencia de Muestreo
                            t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x1 = exp(t); %Generación de Señal
                            subplot(2,1,1),plot(t,x1),grid on %Grafica
                                    xe= 1/2 .* (x1+fliplr(x1));
                                    xo= 1/2 .* (x1-fliplr(x1));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                                    hold off;

                            r = app.Base.Value;
                            x2 = r.^(-t); %Generación de Señal
                            subplot(2,1,2),plot(t,x2),grid on %Grafica
                                    xe2= 1/2 .* (x2+fliplr(x2));
                                    xo2= 1/2 .* (x2-fliplr(x2));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(t,xe2,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo2,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs2= xe2+xo2;
                                        plot(t,xs2,"green"),grid on
                                    end
                                    hold off;
                            % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x1).^2);
                            E = trapz(t, abs(x1).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end
              

                    case "Exponencial compleja"
                        %% Onda Exponencial Compleja    
                        set(app.Base, 'Visible', 'on');
                        set(app.BaseLabel, 'Visible', 'on');
                        Fs = 10000; %Frecuencia de Muestreo
                        t = -app.Representacion.Value:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                        t = t * factorEscalamientoTiempo + corrimiento;
                        x = exp(1j.*(2.*t+(pi/4))); %Generación de Señal
                        xRE = real(x); %Cálculo de la parte Real de x(t)
                        xIM = imag(x); %Cálculo de la parte Imaginaria de x(t)
                        xMAG = abs(x); %Cálculo de la Magnitud de x(t)
                        xARG = atan(imag(x)./real(x)); %Cálculo del Argumento de x(t)
                        subplot(2,2,1),plot(t,xRE); grid on, ylim([-2 2])%Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Real de x(t)')
                        subplot(2,2,2),plot(t,xIM); grid on, ylim([-2 2])%Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Imaginaria de x(t)')
                        subplot(2,2,3),plot(t,xMAG); grid on, ylim([-2 2])%Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Magnitud de x(t)')
                        subplot(2,2,4),plot(t,xARG); grid on, ylim([-2 2])%Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Argumento de x(t)')
                        hold off;

                    case "Escalón"
                        %% Señal Escalon en Tiempo Continuo
                            A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x1 = A*heaviside(t); %Generación de Señal
                            x2 = A.*ones(1,length(t)); %Generación de Señal
                            x3 = A.*ustep(t); %Generación de Señal ADSP Toolbox
                            subplot(2,2,1),plot(t,x1),grid on %Grafica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                                    xe= 1/2 .* (x1+fliplr(x1));
                                    xo= 1/2 .* (x1-fliplr(x1));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                                    hold off;
                            subplot(2,2,2),plot(t,x2),grid on %Grafica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ones')
                                   xe2= 1/2 .* (x2+fliplr(x2));
                                   xo2= 1/2 .* (x2-fliplr(x2));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(t,xe2,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo2,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs2= xe2+xo2;
                                        plot(t,xs2,"green"),grid on
                                    end
                                    hold off;

                            subplot(2,2,3),plot(t,x3),grid on %Grafica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ADSP Toolbox')
                                    xe3= 1/2 .* (x3+fliplr(x3));
                                    xo3= 1/2 .* (x3-fliplr(x3));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(t,xe3,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo3,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs3= xe3+xo3;
                                        plot(t,xs3,"green"),grid on
                                    end
                                    hold off;

                            % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x2).^2);
                            E = trapz(t, abs(x2).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end
                            % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x1).^2);
                            E = trapz(t, abs(x1).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end

                    case "Impulso"
                        %% Señal Impulso
                            A = app.Amplitud.Value; %Amplitud
                            n = 0:5; %Tiempo de Simulación
                            n = n * factorEscalamientoTiempo + corrimiento;
                            x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                            %%x2 = A.*ustep(n-3); %Generación de Señal ADSP Toolbox
                            stem(n,x1),grid on,%Gráfica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Impulso con zeros ones')
                                    xe= 1/2 .* (x1+fliplr(x1));
                                    xo= 1/2 .* (x1-fliplr(x1));
                                    if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                    end
                            % Cálculo de la potencia y la energía de la señal
                            P = (1/(n(end)-n(1))) * trapz(n, abs(x1).^2);
                            E = trapz(n, abs(x1).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end

                    case "Rampa"
                        %% Señal Rampa en Tiempo Continuo
                            A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            t1 = 0:1/Fs:app.Representacion.Value; t2 = app.Representacion.Value:1/Fs:2*app.Representacion.Value;r = t1;
                            t = [t1,t2]; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x1 = A.*[zeros(1,length(t1)),r]; %Generación de Señal
                            plot(t,x1),grid on,xlim([0 10]),ylim([0 5])
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa ecuación')
                                    xe= 1/2 .* (x1+fliplr(x1));
                                    xo= 1/2 .* (x1-fliplr(x1));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                            % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x1).^2);
                            E = trapz(t, abs(x1).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end
  
                    case "Rectangular"
                        %% Señal Rectangular en Tiempo Continuo
                            A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            t = -1:1/Fs:1; %Tiempo de simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x = A.*rectpuls(t); %Generación de Señal
                            plot(t,x),grid on %Gráfica %Gráfica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rectángulo con ADSP Toolbox')
                                    xe= 1/2 .* (x+fliplr(x));
                                    xo= 1/2 .* (x-fliplr(x));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                            % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x).^2);
                            E = trapz(t, abs(x).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end


                    case "Triangular"
                        %% Onda Triangular Tiempo Continuo
                            A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            t = -2:1/Fs:2; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x = A*tripuls(t); %Generación de Señal
                            plot(t,x),grid on %Grafica
                            xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Triángulo con ADSP Toolbox')
                                    xe= 1/2 .* (x+fliplr(x));
                                    xo= 1/2 .* (x-fliplr(x));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end
                                    % Cálculo de la potencia y la energía de la señal
                            P = (1/(t(end)-t(1))) * trapz(t, abs(x).^2);
                            E = trapz(t, abs(x).^2);
                            
                            % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end

                    otherwise
                end

            case "Discreta"
                set(app.FrecuenciaKnob_2Label_2, 'Visible', 'on');
                set(app.FrecuenciaD, 'Visible', 'on');
                set(app.Base, 'Visible', 'off');
                set(app.BaseLabel, 'Visible', 'off');
                switch app.Sign.Value
                case "Cuadrada"
                    %% Onda Cuadrada Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        f = app.FrecuenciaD.Value; %Frecuencia Fundamental
                        T = app.Representacion.Value*(1/f); %Número de Periodos a Graficar
                        duty = app.Ciclo.Value; %Ciclo útil
                        n = 0:T; %Tiempo de Simulación
                        n = n * factorEscalamientoTiempo + corrimiento; 
                        x = A*square(2*pi*f*n,duty); %Generación de Señal
                        stem(n,x),grid on%Grafica
                           
                                xe= 1/2 .* (x+fliplr(x));
                                xo= 1/2 .* (x-fliplr(x));
                                if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                end
                                if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                end
                                if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                end
                        % Cálculo de la potencia y la energía de la señal
                        P = (1/(length(n))) * sum(abs(x).^2);
                        E = sum(abs(x).^2);
                        
                        % Determinación del tipo de señal
                        if P < Inf && P > 0
                            fprintf('La señal es de potencia\n');
                        elseif E < Inf && E > 0
                            fprintf('La señal es de energía\n');
                        else
                            fprintf('La señal no es de potencia ni de energía\n');
                        end

                case "Diente de sierra"
                    %% Onda Diente de Sierra Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        f = app.FrecuenciaD.Value; %Frecuencia Fundamental
                        T = app.Representacion.Value*(1/f); %Número de Periodos a Graficar
                        width = app.Ancho.Value; %Ancho
                        n = 0:T; %Tiempo de Simulación
                        n = n * factorEscalamientoTiempo + corrimiento;
                        x = A*sawtooth(2*pi*f*n,width); %Generación de Señal
                        stem(n,x),grid on%Grafica

                                xe= 1/2 .* (x+fliplr(x));
                                xo= 1/2 .* (x-fliplr(x));
                                if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                end
                                if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                end
                                if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                end

                case "Senoidal" 
                    %% Onda Senoidal Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        f = app.FrecuenciaD.Value; %Frecuencia Fundamental
                        phi = pi/2; %Desfase en radianes
                        T = app.Representacion.Value*(1/f); %Número de Periodos a Graficar
                        n = 0:T; %Tiempo de Simulación
                        x = A*cos(2*pi*f*n-phi); %Generación de Señal                  
                        stem(n,x),grid on %Grafica             
                        xe= 1/2 .* (x+fliplr(x));
                        xo= 1/2 .* (x-fliplr(x));
                            if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                            end
                            if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                            end
                            if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                            end
     
                case "Exponencial creciente y decreciente"
                    %% Onda Exponencial en Tiempo Discreto
                        set(app.Base, 'Visible', 'on');
                        set(app.BaseLabel, 'Visible', 'on');
                        n = 0:app.Representacion.Value; %Tiempo de Simulación
                        n = n * factorEscalamientoTiempo + corrimiento;
                        x1 = exp(n); %Generación de Señal
                        subplot(2,1,1),stem(n,x1),grid on %Grafica
                                    xe= 1/2 .* (x1+fliplr(x1));
                                    xo= 1/2 .* (x1-fliplr(x1));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                    end
                                    hold off;
                        r = app.Base.Value;
                        x2 = app.Amplitud.Value.*r.^(-n); %Generación de Señal
                        subplot(2,1,2),stem(n,x2),grid on %Grafica
                                    xe2= 1/2 .* (x2+fliplr(x2));
                                    xo2= 1/2 .* (x2-fliplr(x2));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        stem(n,xe2,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        stem(n,xo2,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs2= xe2+xo2;
                                        stem(n,xs2,"green"),grid on
                                    end
                                    hold off;

                case "Exponencial compleja"
                    %% Onda Exponencial Compleja en Tiempo Discreto
                    set(app.Base, 'Visible', 'on');
                    set(app.BaseLabel, 'Visible', 'on');
%                     n = -app.Representacion.Value:0.1:app.Representacion.Value; %Tiempo de Simulación
%                     x = exp(1j.*(2.*n+(pi/4))); %Generación de Señal
%                     xRE = real(x); %Cálculo de la parte Real de x[n]
%                     xIM = imag(x); %Cálculo de la parte Imaginaria de x[n]
%                     xMAG = abs(x); %Cálculo de la Magnitud de x[n]
%                     xARG = atan(imag(x)./real(x)); %Cálculo del Argumento de x[n]
%                     subplot(2,2,1),stem(n,xRE); grid on, ylim([-2 2])%Gráfica
%                     xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Real de x[n]')
%                     subplot(2,2,2),stem(n,xIM); grid on, ylim([-2 2])%Gráfica
%                     xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Imaginaria de x[n]')
%                     subplot(2,2,3),stem(n,xMAG); grid on, ylim([-2 2])%Gráfica
%                     xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Magnitud de x[n]')
%                     subplot(2,2,4),stem(n,xARG); grid on, ylim([-2 2])%Gráfica
%                     xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Argumento de x[n]')
                    n = -app.Representacion.Value:0.1:app.Representacion.Value; %Tiempo de Simulación
                    n = n * factorEscalamientoTiempo + corrimiento;
                    x = exp(1j.*(2.*n+(pi/4))); %Generación de Señal
                    xRE = real(x); %Cálculo de la parte Real de x[n]
                    xIM = imag(x); %Cálculo de la parte Imaginaria de x[n]
                    xMAG = abs(x); %Cálculo de la Magnitud de x[n]
                    xARG = atan(imag(x)./real(x)); %Cálculo del Argumento de x[n]

                    % Cálculo de la parte par e impar de la señal
                    xpar = (x + conj(x))/2;
                    ximpar = (x - conj(x))/2;
                    
                    % Gráficas
                    subplot(2,2,1),stem(n,xRE); grid on, ylim([-2 2])%Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Real de x[n]')
                    subplot(2,2,2),stem(n,xIM); grid on, ylim([-2 2])%Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte Imaginaria de x[n]')
                    subplot(2,2,3),stem(n,xMAG); grid on, ylim([-2 2])%Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Magnitud de x[n]')
                    subplot(2,2,4),stem(n,xARG); grid on, ylim([-2 2])%Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Argumento de x[n]')
                    
                    % Gráficas de la parte par e impar de la señal
                    figure
                    subplot(2,1,1),stem(n,xpar); grid on, ylim([-2 2])%Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte par de x[n]')
                    subplot(2,1,2),stem(n,ximpar); grid on, ylim([-2 2])%Gráfica
                    xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Parte impar de x[n]')

                case "Escalón" 
                    %% Señal Escalon en Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        n = 0:app.Representacion.Value; %Tiempo de Simulación
                        n = n * factorEscalamientoTiempo + corrimiento;
                        x1 = A*heaviside(n); %Generación de Señal
                        x2 = A.*ones(1,length(n)); %Generación de Señal
                        x3 = A.*ustep(n); %Generación de Señal ADSP Toolbox
                        subplot(2,2,1),stem(n,x1),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con heaviside')
                                    xe= 1/2 .* (x1+fliplr(x1));
                                    xo= 1/2 .* (x1-fliplr(x1));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                    end
                                    hold off;
                        subplot(2,2,2),stem(n,x2),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ones')
                                    xe2= 1/2 .* (x2+fliplr(x2));
                                    xo2= 1/2 .* (x2-fliplr(x2));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(n,xe2,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(n,xo2,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs2= xe2+xo2;
                                        plot(n,xs2,"green"),grid on
                                    end
                                    hold off;
                        subplot(2,2,3),stem(n,x3),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Escalón con ADSP Toolbox') 
                                    xe3= 1/2 .* (x3+fliplr(x3));
                                    xo3= 1/2 .* (x3-fliplr(x3));
                                     hold on
                                    if app.Componente.Value=="Par"
                                        plot(n,xe3,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(n,xo3,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs3= xe3+xo3;
                                        plot(n,xs3,"green"),grid on
                                    end
                                    hold off;

                case "Impulso" 
                    %% Señal Impulso
                        A = app.Amplitud.Value; %Amplitud
                        n = 0:5; %Tiempo de Simulación
                        n = n * factorEscalamientoTiempo + corrimiento;
                        x1 = A.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                        stem(n,x1),grid on %Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'),title('Impulso con zeros ones')
                                xe= 1/2 .* (x1+fliplr(x1));
                                xo= 1/2 .* (x1-fliplr(x1));
                                if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                end
                                if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                end
                                if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                end

                case "Rampa"
                    %% Señal Rampa en Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        n1 = 0:5; n2 = 5:10;r = n1;
                        n = [n1,n2]; %Tiempo de Simulación
                        n = n .* factorEscalamientoTiempo + corrimiento;
                        x1 = A.*[zeros(1,length(n1)),r]; %Generación de Señal                   
                        stem(n,x1),grid on %Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rampa ecuación')
                                xe= 1/2 .* (x1+fliplr(x1));
                                xo= 1/2 .* (x1-fliplr(x1));
                                if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                end
                                if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                end
                                if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                end


                case "Rectangular" 
                    %% Señal Rectangular en Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        n = -1:0.1:1; %Tiempo de Simulación
                        n = n * factorEscalamientoTiempo + corrimiento;
                        x = A.*rectpuls(n); %Generación de Señal
                        stem(n,x),grid on %Gráfica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Rectángulo con ADSP Toolbox')
                                xe= 1/2 .* (x+fliplr(x));
                                xo= 1/2 .* (x-fliplr(x));
                                if app.Componente.Value=="Par"
                                        stem(n,xe,"cyan"),grid on
                                end
                                if app.Componente.Value=="Impar"
                                        stem(n,xo,"red"),grid on
                                end
                                if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(n,xs,"green"),grid on
                                end
                         %Cálculo de Potencia y Energía
                         P=(1/(n(end)-n(1))) * trapz(n,abs(x).^2);
                         E = trapz(n, abs(x).^2);
                         % Determinación del tipo de señal
                         if P >0 && E==Inf
                                fprintf('La señal es de potencia\n');
                         elseif E < Inf && P==0
                                fprintf('La señal es de energía\n');
                         else
                                fprintf('La señal no es de potencia ni de energía\n');
                         end

                case "Triangular"
                    %% Onda Traingular Tiempo Discreto
                        A = app.Amplitud.Value; %Amplitud
                        r= -2:0.1:2; %Tiempo de Simulación
                        r = r * factorEscalamientoTiempo + corrimiento;
                        x = A*tripuls(r); %Generación de Señal
                        stem(r,x),grid on %Grafica
                        xlabel('Tiempo (s)'),ylabel('Amplitud'), title('Triángulo con ADSP Toolbox')
                                xe= 1/2 .* (x+fliplr(x));
                                xo= 1/2 .* (x-fliplr(x));
                                if app.Componente.Value=="Par"
                                        stem(r,xe,"cyan"),grid on
                                end
                                if app.Componente.Value=="Impar"
                                        stem(r,xo,"red"),grid on
                                end
                                if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        stem(r,xs,"green"),grid on
                                end
                      %Cálculo de Potencia y Energía
                         P=(1/(r(end)-r(1))) * trapz(r,abs(x).^2);
                         E = trapz(r, abs(x).^2);
                         % Determinación del tipo de señal
                         if P >0 && E==Inf
                                fprintf('La señal es de potencia\n');
                         elseif E < Inf && P==0
                                fprintf('La señal es de energía\n');
                         else
                                fprintf('La señal no es de potencia ni de energía\n');
                         end
                otherwise
                end
                 case "Periodica"
                     switch app.Sign.Value
                         case "Cuadrada"
                             A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            f = app.Frecuencia.Value; %Frecuencia Fundamental
                            % T = 3*(1/f); %Número de Periodos a Graficar
                            duty = app.Ciclo.Value; %Ciclo útil
                            t = app.Representacion.Value/100:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                            t = t * factorEscalamientoTiempo + corrimiento;
                            x = A*square(2*pi*f*t,duty); %Generación de Señal
                            plot(t,x),grid on %Grafica
                            title("Señal Cuadrada Periódica")

                                    xe= 1/2 .* (x+fliplr(x));
                                    xo= 1/2 .* (x-fliplr(x));
                                    if app.Componente.Value=="Par"
                                        plot(t,xe,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,xo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        xs= xe+xo;
                                        plot(t,xs,"green"),grid on
                                    end

                           %Cálculo de Potencia y Energía
                           P=(1/(t(end)-t(1))) * trapz(t,abs(x).^2);
                           E = trapz(t, abs(x).^2);
                           % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end
                     end
                     
                 case "Aperiodica"
                     switch app.Sign.Value
                     case "Cuadrada"
                        %%  Onda Cuadrada en tiempo continuo
                            A = app.Amplitud.Value; %Amplitud
                            Fs = 10000; %Frecuencia de Muestreo
                            f = app.Frecuencia.Value; %Frecuencia Fundamental
                            T = 3*(1/f); %Número de Periodos a Graficar
                            duty = app.Ciclo.Value; %Ciclo útil
                            t = app.Representacion.Value/100:1/Fs:app.Representacion.Value; %Tiempo de Simulación
                                % Generación de Señal Aperiódica
                            f1 = 10*f; % Frecuencia de la primera señal senoidal
                            f2 = 20*f; % Frecuencia de la segunda señal senoidal
                            A1 = A/10; % Amplitud de la primera señal senoidal
                            A2 = A/20; % Amplitud de la segunda señal senoidal
                            y = A1*sin(2*pi*f1*t) + A2*sin(2*pi*f2*t); % Señal Aperiódica
                            % Graficación de Señales
                            plot(t,y) % Señal Aperiódica
                            title("Señal Cuadrada Aperiódica")

                                    ye= 1/2 .* (y+fliplr(y));
                                    yo= 1/2 .* (y-fliplr(y));
                                    if app.Componente.Value=="Par"
                                        plot(t,ye,"cyan"),grid on
                                    end
                                    if app.Componente.Value=="Impar"
                                        plot(t,yo,"red"),grid on
                                    end
                                    if app.Componente.Value=="Suma"
                                        ys= ye+yo;
                                        plot(t,ys,"green"),grid on
                                    end

                           %Cálculo de Potencia y Energía
                           P=(1/(t(end)-t(1))) * trapz(t,abs(y).^2);
                           E = trapz(t, abs(y).^2);
                           % Determinación del tipo de señal
                            if P < Inf && P > 0
                                fprintf('La señal es de potencia\n');
                            elseif E < Inf && E > 0
                                fprintf('La señal es de energía\n');
                            else
                                fprintf('La señal no es de potencia ni de energía\n');
                            end
                         case "Diente de sierra"
                            A = app.Amplitud.Value; %Amplitud
f = app.Frecuencia.Value; %Frecuencia Fundamental
Fs = 10000; %Frecuencia de Muestreo
width = app.Ancho.Value; %Ancho
t = 0:1/Fs:app.Representacion.Value; %Tiempo de Simulación
x = A*sawtooth(2*pi*f*t,width); %Generación de Señal

% Filtro Pasaaltos
fc = f*1.1; % Frecuencia de corte
N = 1000; % Orden del filtro
h = fir1(N, fc/(Fs/2), 'high'); % Coeficientes del filtro

% Filtrado de la señal
y = filter(h, 1, x);

% Señal aperiódica
x_ap = x - y;

% Señal periódica
x_per = y;

% Graficación de las señales
figure;
subplot(3,1,1);
plot(t, x);
title('Señal original');
subplot(3,1,2);
plot(t, x_ap);
title('Señal aperiódica');
subplot(3,1,3);
plot(t, x_per);
title('Señal periódica');

grid on;


                     end
            end
            
        end

        % Drop down opening function: Sign
        function SignDropDownOpening(app, event)
            
        end

        % Value changed function: RepresentacionDrop
        function RepresentacionDropValueChanged(app, event)
        
        end

        % Callback function
        function BaseValueChanged(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.Sign = uidropdown(app.UIFigure);
            app.Sign.Items = {'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial creciente y decreciente', 'Exponencial compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Sign.DropDownOpeningFcn = createCallbackFcn(app, @SignDropDownOpening, true);
            app.Sign.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Sign.Value = 'Cuadrada';
            app.AmplitudKnob_2Label = uilabel(app.UIFigure);
            app.AmplitudKnob_2Label.Text = 'Amplitud';
            app.Amplitud = uiknob(app.UIFigure, 'continuous');
            app.Amplitud.Limits = [0 2];
            app.Amplitud.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.Base = uiknob(app.UIFigure, 'continuous');
            app.Base.Limits = [-5 5];
            app.Base.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Base.Value = 1;
            app.CicloKnob_2Label = uilabel(app.UIFigure);
            app.CicloKnob_2Label.Text = 'Ciclo';
            app.Ciclo = uiknob(app.UIFigure, 'continuous');
            app.Ciclo.Limits = [50 100];
            app.Ciclo.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Ciclo.Value = 50;
            app.FrecuenciaKnob_2Label = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label.Text = 'Frecuencia';
            app.Frecuencia = uiknob(app.UIFigure, 'continuous');
            app.Frecuencia.Limits = [0 230];
            app.Frecuencia.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Frecuencia.Value = 50;
            app.AnchoKnob_2Label = uilabel(app.UIFigure);
            app.AnchoKnob_2Label.Text = 'Ancho';
            app.Ancho = uiknob(app.UIFigure, 'continuous');
            app.Ancho.Limits = [0 1];
            app.Ancho.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.RepresentacinLabel = uilabel(app.UIFigure);
            app.RepresentacinLabel.Text = 'Representación';
            app.RepresentacionDrop = uidropdown(app.UIFigure);
            app.RepresentacionDrop.Items = {'Continua', 'Discreta', 'Periodica', 'Aperiodica'};
            app.RepresentacionDrop.ValueChangedFcn = createCallbackFcn(app, @RepresentacionDropValueChanged, true);
            app.RepresentacionDrop.Value = 'Continua';
            app.FrecuenciaKnob_2Label_2 = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label_2.Text = 'FrecuenciaD';
            app.FrecuenciaD = uiknob(app.UIFigure, 'continuous');
            app.FrecuenciaD.Limits = [0.1 1];
            app.FrecuenciaD.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FrecuenciaD.Value = 0.1;
            app.EscaladetiempoLabel = uilabel(app.UIFigure);
            app.EscaladetiempoLabel.Text = 'Escala de tiempo';
            app.Representacion = uislider(app.UIFigure);
            app.Representacion.Limits = [0 20];
            app.Representacion.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Representacion.Value = 10;
            app.ComponenteLabel = uilabel(app.UIFigure);
            app.ComponenteLabel.Text = 'Componente';
            app.Componente = uidropdown(app.UIFigure);
            app.Componente.Items = {'Seleccion', 'Par', 'Impar', 'Suma'};
            app.Componente.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Componente.Value = 'Seleccion';
            app.OperacionessobrelavariableindependienteLabel = uilabel(app.UIFigure);
            app.OperacionessobrelavariableindependienteLabel.Text = 'Operaciones sobre la variable independiente';
            app.ReflexionSwitchLabel = uilabel(app.UIFigure);
            app.ReflexionSwitchLabel.Text = 'Reflexion';
            app.ReflexionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReflexionSwitch.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FactordeEscalamientoSpinnerLabel = uilabel(app.UIFigure);
            app.FactordeEscalamientoSpinnerLabel.Text = 'Factor de Escalamiento';
            app.FactordeEscalamientoSpinner = uispinner(app.UIFigure);
            app.FactordeEscalamientoSpinner.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FactordeCorrimientoSpinnerLabel = uilabel(app.UIFigure);
            app.FactordeCorrimientoSpinnerLabel.Text = 'Factor de Corrimiento';
            app.FactordeCorrimientoSpinner = uispinner(app.UIFigure);
            app.FactordeCorrimientoSpinner.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_generator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end