classdef signal_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Tipo                         matlab.ui.control.Label
        TipodesealLabel              matlab.ui.control.Label
        Frecuencia_2                 matlab.ui.control.Knob
        FrecuenciaF2Label            matlab.ui.control.Label
        Ancho_2                      matlab.ui.control.Knob
        AnchoF2Label                 matlab.ui.control.Label
        Ciclo_2                      matlab.ui.control.Knob
        AmplitudF2Label_2            matlab.ui.control.Label
        Base_2                       matlab.ui.control.Knob
        BaseF2Label                  matlab.ui.control.Label
        Amplitud_2                   matlab.ui.control.Knob
        AmplitudF2Label              matlab.ui.control.Label
        op                           matlab.ui.control.DropDown
        OperacionLabel               matlab.ui.control.Label
        f2                           matlab.ui.control.DropDown
        SealparaoperacionesLabel     matlab.ui.control.Label
        FactordeCorrimientoSpinner   matlab.ui.control.Spinner
        FactordeCorrimientoSpinnerLabel  matlab.ui.control.Label
        FactordeEscalamientoSpinner  matlab.ui.control.Spinner
        FactordeEscalamientoSpinnerLabel  matlab.ui.control.Label
        ReflexionSwitch              matlab.ui.control.Switch
        ReflexionSwitchLabel         matlab.ui.control.Label
        OperacionessobrelavariableindependienteLabel  matlab.ui.control.Label
        Componente                   matlab.ui.control.DropDown
        ComponenteLabel              matlab.ui.control.Label
        Representacion               matlab.ui.control.Slider
        EscaladetiempoLabel          matlab.ui.control.Label
        RepresentacionDrop           matlab.ui.control.DropDown
        RepresentacinLabel           matlab.ui.control.Label
        Ancho                        matlab.ui.control.Knob
        AnchoKnob_2Label             matlab.ui.control.Label
        Frecuencia                   matlab.ui.control.Knob
        FrecuenciaKnob_2Label        matlab.ui.control.Label
        Ciclo                        matlab.ui.control.Knob
        CicloKnob_2Label             matlab.ui.control.Label
        Base                         matlab.ui.control.Knob
        BaseLabel                    matlab.ui.control.Label
        Amplitud                     matlab.ui.control.Knob
        AmplitudKnob_2Label          matlab.ui.control.Label
        Sign                         matlab.ui.control.DropDown
        SealDropDownLabel            matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    methods (Access = private)
    function addLibToPath(app)
        addpath(fullfile(app.PathToApp, 'lib'));
    end

%     function ep(app,n ,x)
%         P=(1/(n(end)-n(1))) * trapz(n,abs(x).^2);
%         E = trapz(n, abs(x).^2);
%         % Determinación del tipo de señal
% 
%         if P < Inf && P > 0
%             u='Potencia';
%         elseif E < Inf && E > 0
%             u='energía\n';
%         else
%             u='Ninguna de las dos';
%         end
%         app.Tipo.Text=u;
%        

%     end
    
    function plotGraphOddPairSegments(app, n, x)
        xe = 1/2 * (x + fliplr(x));
        xo = 1/2 * (x - fliplr(x));

        if app.Componente.Value == "Par"
            % Check if the plot type is stem or plot
            if app.RepresentacionDrop.Value == "Discreta"
                stem(n, xe, 'cyan'), grid on
            elseif app.RepresentacionDrop.Value == "Continua"
                plot(n, xe, 'cyan'), grid on
            end
        end

        if app.Componente.Value == "Impar"
            % Check if the plot type is stem or plot
            if app.RepresentacionDrop.Value == "Discreta"
                stem(n, xo, 'red'), grid on
            elseif app.RepresentacionDrop.Value == "Continua"
                plot(n, xo, 'red'), grid on
            end
        end

        if app.Componente.Value == "Suma"
            xs = xe + xo;
            % Check if the plot type is stem or plot
            if app.RepresentacionDrop.Value == "Discreta"
                stem(n, xs, 'green'), grid on
            elseif app.RepresentacionDrop.Value == "Continua"
                plot(n, xs, 'green'), grid on
            end
        end
    end
        
    function plotGraph(app, n, x, x_label, y_label, graphTitle,graphType)
        % Check if the plot type is stem or plot
        if app.RepresentacionDrop.Value == "Discreta"
            % Plot a stem graph
            stem(n, x),
        elseif app.RepresentacionDrop.Value == "Continua"
            % Plot a regular line graph
            if length(x) ~= length(n)
                n = n(:, 1:length(x));
            end
            plot(n, x),
        elseif app.RepresentacionDrop.Value =="Periodica"
            if graphType==0
                 x = repmat(x, 1, 5); % Repetir señal n veces -> n=5
                 n= 0:1/1000:length(x)/1000-1/1000; %Nuevo vector de tiempo
                 plot(n,x)
            else 
                plot(n,x)
            end
        else
            if graphType==1

                %Obtener el índice de la última muestra del primer ciclo
                lastSampleIndex = round(1000/app.Frecuencia.Value);
                %Obtener los vectores de tiempo y señal correspondientes a un ciclo
                t = n(1:lastSampleIndex);
                x = x(1:lastSampleIndex);
                plot(t,x)

            else
                plot(n,x)
            end
        end
         grid on,
            xlabel(x_label), ylabel(y_label), title(graphTitle)
        
        plotGraphOddPairSegments(app, n, x)
     
    end
    function fx = getOpFunction(app)
        % Operaciones sobre variable independiente
        factorEscalamientoTiempo = app.FactordeEscalamientoSpinner.Value;
        
        if app.ReflexionSwitch.Value == "On"
            factorEscalamientoTiempo = -factorEscalamientoTiempo;
        end
            
        corrimiento = app.FactordeCorrimientoSpinner.Value;
        
        fs = 1000; % frecuencia fundamental para senales continuas
        
        if app.RepresentacionDrop.Value == "Discreta"
            fs = 1;
        end

        A = app.Amplitud_2.Value; %Amplitud
        f = app.Frecuencia_2.Value; %Frecuencia Fundamental
        duty = app.Ciclo_2.Value; %Ciclo útil
        width = app.Ancho_2.Value; %Ancho
        
        T = app.Representacion.Value * (1/f); %Periodos a graficar
        t = 0:1/fs:T;
        t = t * factorEscalamientoTiempo + corrimiento;
        
        r = app.Base_2.Value;
        
        fx = 0;
        
        switch app.f2.Value
                case "Cuadrada"
                    fx = A*square(2*pi*f*t,duty); %Generación de Señal
                case "Diente de sierra"
                    fx = A*sawtooth(2*pi*f*t,width);
                case "Senoidal"
                    phi = pi/2;
                    fx =  A*sin(2*pi*f*t+phi);
                case "Exponencial creciente"
                    fx = exp(t);
                case "Exponencial decreciente"
                    fx = r.^(-t);
                case "Exponencial compleja"
                    fx = exp(1j.*(2.*t+(pi/4)));
                case "Escalón"
                    fx = A*heaviside(t); %Generación de Señal
                case "Impulso"
                    t = 0:5;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    fx = A.*[zeros(1,2), ones(1,1), zeros(1,3)];
                case "Rampa"
                    t1 = 0:1/fs:5;
                    t1 = t1 * factorEscalamientoTiempo + corrimiento;
                    t2 = 5:1/fs:10;
                    t2 = t2 * factorEscalamientoTiempo + corrimiento;
                    r = t1;
                    t = [t1, t2];
                    fx =  A.*[zeros(1,length(t1)),r];
                case "Rectangular"
                    t = -1:1/fs:1;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    fx = A.*rectpuls(t);
                case "Triangular"
                    t = -2:1/fs:2;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    fx = A*tripuls(t);  
        end
    end
    
    function f = operate(~, f1, f2, operation)
        if length(f2) < length(f1)
            f2 = [f2, zeros(1, length(f1) - length(f2))];
        elseif length(f1) < length(f2)
            f2 = f2(1:length(f1));
        end
        switch operation
            case "+"
                f = f1 + f2;
            case "x"
                f = f1 .* f2; 
            case "dx/dt"
                f = diff(f1);
            case "Ninguna"
                f = f1;
            case "int"
                f = f1;
        end
    end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, as)
            
        end

        % Callback function
        function AmplitudSliderValueChanged(app, event)
            
        end

        % Value changed function: Amplitud, Amplitud_2, Ancho, Ancho_2, 
        % ...and 15 other components
        function SignValueChanged(app, event)
            title(app.UIAxes,app.Sign.Value);
            cla(app.UIAxes);
            
            % Operaciones sobre variable independiente
            factorEscalamientoTiempo = app.FactordeEscalamientoSpinner.Value;
            
            if app.ReflexionSwitch.Value == "On"
                factorEscalamientoTiempo = -factorEscalamientoTiempo;
            end
                
            corrimiento = app.FactordeCorrimientoSpinner.Value;
            
            fs = 1000; % frecuencia fundamental para senales continuas
            
            if app.RepresentacionDrop.Value == "Discreta"
                fs = 1;
            end

            A = app.Amplitud.Value; %Amplitud
            f = app.Frecuencia.Value; %Frecuencia Fundamental
            duty = app.Ciclo.Value; %Ciclo útil
            width = app.Ancho.Value; %Ancho
            type1 = 1; %Tipo de grafica por defecto -> Periodica
            type0 = 0; %Tipo de grafica por defecto -> Aperiodica
            T = app.Representacion.Value * (1/f); %Periodos a graficar
            t = 0:1/fs:T;
            t = t * factorEscalamientoTiempo + corrimiento;
            
            r = app.Base.Value;
            
            operation = app.op.Value;
            
            fOp = getOpFunction(app);
            
            syms tiempo;
                        
            switch app.Sign.Value
                case "Cuadrada"
%                     fintx = A*square(tiempo);
%                     fintx = int(fintx, tiempo);
%                     fintx = double(subs(fintx, tiempo, t));

                    fx = A*square(2*pi*f*t,duty); %Generación de Señal
                    fx = operate(app, fx, fOp, operation);
                    
%                     if operation == "int"
%                         fx = 0;
%                     end
                    
                    plotGraph(app, t, fx, "", "", "",type1)
%                     ep(app,t,fx);
                case "Diente de sierra"
%                     fintx = A*sawtooth(tiempo);
%                     fintx = int(fintx, tiempo);
%                     fintx = double(subs(fintx, tiempo, t));

                    fx = A*sawtooth(2*pi*f*t,width);
                    fx = operate(app, fx, fOp, app.op.Value);
                    
%                     if operation == "int"
%                         fx = fintx;
%                     end
                    
                    plotGraph(app, t, fx, "", "", "",type1)
%                     ep(app,t,fx)
                case "Senoidal"
                    fintx = A*sin(tiempo);
                    fintx = int(fintx, tiempo);
                    fintx = double(subs(fintx, tiempo, t));
                    
                    phi = pi/2;
                    fx =  A*sin(2*pi*f*t+phi);
                    fx = operate(app, fx, fOp, app.op.Value);
                    
                    if operation == "int"
                        fx = fintx;
                    end
                    plotGraph(app, t, fx, "", "", "",type1)
%                     ep(app,t,fx);
                case "Exponencial creciente y decreciente"
                    fintx1 = A*exp(tiempo);
                    fintx1 = int(fintx1, tiempo);
                    fintx1 = double(subs(fintx1, tiempo, t));
                    
                    fintx2 = A*r.^(-tiempo);
                    fintx2 = int(fintx2, tiempo);
                    fintx2 = double(subs(fintx2, tiempo, t));
                    
                    fx1 = exp(t);
                    fx1 = operate(app, fx1, fOp, app.op.Value);
                    fx2 = A*r.^(-t);
                    fx2 = operate(app, fx2, fOp, app.op.Value);
                    
                    if operation == "int"
                        fx1 = fintx1;
                        fx2 = fintx2;
                    end
                    
                    subplot(2, 1, 1), plotGraph(app, t, fx1, "", "", "",type0);
                    subplot(2, 1, 2), plotGraph(app, t, fx2, "", "", "",type0);
%                     ep(app,t,fx1)
                case "Exponencial compleja"                    
                    fx = A*exp(1j.*(2.*t+(pi/4)));
%                     ep(app,t,fx);
                    fx = operate(app, fx, fOp, app.op.Value);
                    xRE = real(fx); %Cálculo de la parte Real de x(t)
                    xIM = imag(fx); %Cálculo de la parte Imaginaria de x(t)
                    xMAG = abs(fx); %Cálculo de la Magnitud de x(t)
                    xARG = atan(imag(fx)./real(fx)); %Cálculo del Argumento de x(t)
                    subplot(2,2,1), plotGraph(app, t, xRE, "Tiempo(s)", "Amplitud", "Parte Re de fx(t)",type1);
                    subplot(2,2,2), plotGraph(app, t, xIM, "Tiempo(s)", "Amplitud", "Parte Im de fx(t)",type1);
                    subplot(2,2,3), plotGraph(app, t, xMAG, "Tiempo(s)", "Amplitud", "Parte xMAG de fx(t)",type1);
                    subplot(2,2,4), plotGraph(app, t, xARG, "Tiempo(s)", "Amplitud", "Parte XArg de fx(t)",type1);
                    hold off;
                case "Escalón"
                    fintx = A*heaviside(tiempo);
                    fintx = int(fintx, tiempo);
                    fintx = double(subs(fintx, tiempo, t));
                          
                    x1 = A*heaviside(t); %Generación de Señal
%                     ep(app,t,x1);
                    x1 = operate(app, x1,fOp, app.op.Value);
                    x2 = A.*ones(1,length(t)); %Generación de Señal
                    x2 = operate(app, x2, fOp, app.op.Value);
                    x3 = A.*ustep(t); %Generación de Señal ADSP Toolbox
                    x3 = operate(app, x3, fOp, app.op.Value);
                    subplot(2, 2, 1), plotGraph(app, t, x1, "Tiempo(s)", "Amplitud", "Escalon con heaviside",type1);
                    subplot(2, 2, 2), plotGraph(app, t, x2, "Tiempo(s)", "Amplitud", "Escalon con ones",type1);
                    subplot(2, 2, 3), plotGraph(app, t, x3, "Tiempo(s)", "Amplitud", "Escalon con ustep",type1);
                    subplot(2, 2, 4), plotGraph(app, t, fintx, "Tiempo(s)", "Amplitud", "Integral",type1);
                    
                case "Impulso"
                    t = 0:5;
                    x1 = A.*[zeros(1,2), ones(1,1), zeros(1,3)];
%                     ep(app,t,x1);
                    t = t * factorEscalamientoTiempo + corrimiento;
                    operate(app, x1, fOp, operation);
                    
                    plotGraph(app, t, x1, "", "", "",type0);
                case "Rampa"
                    t1 = 0:1/fs:5;
                    t1 = t1 * factorEscalamientoTiempo + corrimiento;
                    t2 = 5:1/fs:10;
                    t2 = t2 * factorEscalamientoTiempo + corrimiento;
                    r = t1;
                    t = [t1, t2];
                    x1 =  A.*[zeros(1,length(t1)),r];
%                     ep(app,t,x1);
                    operate(app, x1, fOp, operation);
                    
                    plotGraph(app, t, x1, "", "", "",type0);
%                     ep(app,t,x1)
                case "Rectangular"
                    t = -1:1/fs:1;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    x = A.*rectpuls(t);
                    operate(app, x, fOp, operation);
                    plotGraph(app, t, x, "Tiempo (s)", "Amplitud", "Rectangular con rectpuls",type0);
%                     ep(app,t,x);
                case "Triangular"
                    t = -2:1/fs:2;
                    t = t * factorEscalamientoTiempo + corrimiento;
                    x = A*tripuls(t);
%                     ep(app,t,x);
                    operate(app, x, fOp, operation);
                    plotGraph(app, t, x, "Tiempo(s)", "Amplitud", "Triangular con tripuls",type0);
                
            end
            
        end

        % Drop down opening function: Sign
        function SignDropDownOpening(app, event)
            
        end

        % Callback function
        function RepresentacionDropValueChanged(app, event)
        
        end

        % Callback function
        function BaseValueChanged(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.Sign = uidropdown(app.UIFigure);
            app.Sign.Items = {'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial creciente y decreciente', 'Exponencial compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.Sign.DropDownOpeningFcn = createCallbackFcn(app, @SignDropDownOpening, true);
            app.Sign.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Sign.Value = 'Cuadrada';
            app.AmplitudKnob_2Label = uilabel(app.UIFigure);
            app.AmplitudKnob_2Label.Text = 'Amplitud';
            app.Amplitud = uiknob(app.UIFigure, 'continuous');
            app.Amplitud.Limits = [0 2];
            app.Amplitud.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Amplitud.Value = 1;
            app.BaseLabel = uilabel(app.UIFigure);
            app.BaseLabel.Text = 'Base';
            app.Base = uiknob(app.UIFigure, 'continuous');
            app.Base.Limits = [-5 5];
            app.Base.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Base.Value = 1;
            app.CicloKnob_2Label = uilabel(app.UIFigure);
            app.CicloKnob_2Label.Text = 'Ciclo';
            app.Ciclo = uiknob(app.UIFigure, 'continuous');
            app.Ciclo.Limits = [50 100];
            app.Ciclo.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Ciclo.Value = 50;
            app.FrecuenciaKnob_2Label = uilabel(app.UIFigure);
            app.FrecuenciaKnob_2Label.Text = 'Frecuencia';
            app.Frecuencia = uiknob(app.UIFigure, 'continuous');
            app.Frecuencia.Limits = [0.1 10];
            app.Frecuencia.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Frecuencia.Value = 5;
            app.AnchoKnob_2Label = uilabel(app.UIFigure);
            app.AnchoKnob_2Label.Text = 'Ancho';
            app.Ancho = uiknob(app.UIFigure, 'continuous');
            app.Ancho.Limits = [0 1];
            app.Ancho.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.RepresentacinLabel = uilabel(app.UIFigure);
            app.RepresentacinLabel.Text = 'Representación';
            app.RepresentacionDrop = uidropdown(app.UIFigure);
            app.RepresentacionDrop.Items = {'Continua', 'Discreta', 'Periodica', 'Aperiodica'};
            app.RepresentacionDrop.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.RepresentacionDrop.Value = 'Continua';
            app.EscaladetiempoLabel = uilabel(app.UIFigure);
            app.EscaladetiempoLabel.Text = 'Escala de tiempo';
            app.Representacion = uislider(app.UIFigure);
            app.Representacion.Limits = [0 20];
            app.Representacion.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Representacion.Value = 10;
            app.ComponenteLabel = uilabel(app.UIFigure);
            app.ComponenteLabel.Text = 'Componente';
            app.Componente = uidropdown(app.UIFigure);
            app.Componente.Items = {'Seleccion', 'Par', 'Impar', 'Suma'};
            app.Componente.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Componente.Value = 'Seleccion';
            app.OperacionessobrelavariableindependienteLabel = uilabel(app.UIFigure);
            app.OperacionessobrelavariableindependienteLabel.Text = 'Operaciones sobre la variable independiente';
            app.ReflexionSwitchLabel = uilabel(app.UIFigure);
            app.ReflexionSwitchLabel.Text = 'Reflexion';
            app.ReflexionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReflexionSwitch.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FactordeEscalamientoSpinnerLabel = uilabel(app.UIFigure);
            app.FactordeEscalamientoSpinnerLabel.Text = 'Factor de Escalamiento';
            app.FactordeEscalamientoSpinner = uispinner(app.UIFigure);
            app.FactordeEscalamientoSpinner.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FactordeEscalamientoSpinner.Value = 1;
            app.FactordeCorrimientoSpinnerLabel = uilabel(app.UIFigure);
            app.FactordeCorrimientoSpinnerLabel.Text = 'Factor de Corrimiento';
            app.FactordeCorrimientoSpinner = uispinner(app.UIFigure);
            app.FactordeCorrimientoSpinner.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.SealparaoperacionesLabel = uilabel(app.UIFigure);
            app.SealparaoperacionesLabel.Text = 'Señal para operaciones';
            app.f2 = uidropdown(app.UIFigure);
            app.f2.Items = {'Cuadrada', 'Diente de sierra', 'Senoidal', 'Exponencial creciente', 'Exponencial decreciente', 'Exponencial compleja', 'Escalón', 'Impulso', 'Rampa', 'Rectangular', 'Triangular'};
            app.f2.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.f2.Value = 'Cuadrada';
            app.OperacionLabel = uilabel(app.UIFigure);
            app.OperacionLabel.Text = 'Operacion';
            app.op = uidropdown(app.UIFigure);
            app.op.Items = {'Ninguna', '+', 'x', 'int', 'dx/dt'};
            app.op.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.op.Value = 'Ninguna';
            app.AmplitudF2Label = uilabel(app.UIFigure);
            app.AmplitudF2Label.Text = 'Amplitud F2';
            app.Amplitud_2 = uiknob(app.UIFigure, 'continuous');
            app.Amplitud_2.Limits = [0 2];
            app.Amplitud_2.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Amplitud_2.Value = 1;
            app.BaseF2Label = uilabel(app.UIFigure);
            app.BaseF2Label.Text = 'Base F2';
            app.Base_2 = uiknob(app.UIFigure, 'continuous');
            app.Base_2.Limits = [-5 5];
            app.Base_2.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Base_2.Value = 1;
            app.AmplitudF2Label_2 = uilabel(app.UIFigure);
            app.AmplitudF2Label_2.Text = 'Amplitud F2';
            app.Ciclo_2 = uiknob(app.UIFigure, 'continuous');
            app.Ciclo_2.Limits = [50 100];
            app.Ciclo_2.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Ciclo_2.Value = 50;
            app.AnchoF2Label = uilabel(app.UIFigure);
            app.AnchoF2Label.Text = 'Ancho F2';
            app.Ancho_2 = uiknob(app.UIFigure, 'continuous');
            app.Ancho_2.Limits = [0 1];
            app.Ancho_2.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.FrecuenciaF2Label = uilabel(app.UIFigure);
            app.FrecuenciaF2Label.Text = 'Frecuencia F2';
            app.Frecuencia_2 = uiknob(app.UIFigure, 'continuous');
            app.Frecuencia_2.Limits = [0.1 10];
            app.Frecuencia_2.ValueChangedFcn = createCallbackFcn(app, @SignValueChanged, true);
            app.Frecuencia_2.Value = 5;
            app.TipodesealLabel = uilabel(app.UIFigure);
            app.TipodesealLabel.Text = 'Tipo de señal';
            app.Tipo = uilabel(app.UIFigure);
            app.Tipo.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_generator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end